classdef GUI_IK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label4                   matlab.ui.control.Label
        LabelEditField           matlab.ui.control.Label
        Theta                    matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
        Alpha                    matlab.ui.control.EditField
        Label2                   matlab.ui.control.Label
        d                        matlab.ui.control.EditField
        Label3                   matlab.ui.control.Label
        a                        matlab.ui.control.EditField
        qinitialEditFieldLabel   matlab.ui.control.Label
        qinitialEditField        matlab.ui.control.EditField
        LabelNumericEditField    matlab.ui.control.Label
        n                        matlab.ui.control.NumericEditField
        LabelEditField5          matlab.ui.control.Label
        joints                   matlab.ui.control.EditField
        InverseKinematicsButton  matlab.ui.control.Button
        InverseKinematicsusingJacobianButton  matlab.ui.control.Button
        ROBOTLabel               matlab.ui.control.Label
        LabelTextArea            matlab.ui.control.Label
        TextArea2                matlab.ui.control.TextArea
        TextArea                 matlab.ui.control.TextArea
        TextArea_2               matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            try
            Th = str_process_22(app.Theta.Value);
            Alp = str_process_22(app.Alpha.Value);
            A = str_process_22(app.a.Value);
            D = str_process_22(app.d.Value);
           catch
            Th = str_process(app.Theta.Value);
            Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
           end
            B = app.joints.Value;
            for i=1:length(Alp)
                DHP(i,:)=[Th(i),Alp(i),A(i),D(i)]  
            end
            I_K = inverseKinematics(B,DHP)
            f = figure;
            uit = uitable(f,'ColumnWidth',{200,100,200},'Data',I_K);
        end

        % Button pushed function: 
        % InverseKinematicsusingJacobianButton
        function InverseKinematicsusingJacobianButtonPushed(app, event)
           try
            Th = str_process_22(app.Theta.Value);
            Alp = str_process_22(app.Alpha.Value);
            A = str_process_22(app.a.Value);
            D = str_process_22(app.d.Value);
            Q = str_process_22(app.qinitialEditField.Value);
           catch
            Th = str_process(app.Theta.Value);
            Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
            Q = str_process(app.qinitialEditField.Value);
           end
            B = app.joints.Value;
            for i=1:length(B)              
                DHP(i,:)=[Th(i),Alp(i),A(i),D(i)]  
            end
            I_K = inverseKinematicsUsingJacobian(DHP,Q,B)
            f = figure;
            uit = uitable(f,'ColumnWidth',{200,100,200},'Data',I_K); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'DH Parameters';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Theta';
            app.Theta = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Alpha';
            app.Alpha = uieditfield(app.UIFigure, 'text');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'd';
            app.d = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'text');
            app.qinitialEditFieldLabel = uilabel(app.UIFigure);
            app.qinitialEditFieldLabel.Text = 'q initial ';
            app.qinitialEditField = uieditfield(app.UIFigure, 'text');
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Number of Joints';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.LabelEditField5 = uilabel(app.UIFigure);
            app.LabelEditField5.Text = 'Joint Types';
            app.joints = uieditfield(app.UIFigure, 'text');
            app.InverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.InverseKinematicsusingJacobianButton = uibutton(app.UIFigure, 'push');
            app.InverseKinematicsusingJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsusingJacobianButtonPushed, true);
            app.InverseKinematicsusingJacobianButton.Text = 'Inverse Kinematics using Jacobian';
            app.ROBOTLabel = uilabel(app.UIFigure);
            app.ROBOTLabel.Text = 'ROBOT';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Note';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {'Enter R or P without any spaces.'; 'Eg: If links are Revolute and Prismatic enter RP'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Enter DH parameters separated by '','''};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Enter Theta and alpha in degrees'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_IK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end