classdef GUI_forward_kinematics_and_workspace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LabelTextArea              matlab.ui.control.Label
        LabelNumericEditField      matlab.ui.control.Label
        n                          matlab.ui.control.NumericEditField
        LabelEditField5            matlab.ui.control.Label
        joints                     matlab.ui.control.EditField
        Label4                     matlab.ui.control.Label
        LabelEditField             matlab.ui.control.Label
        Theta                      matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        Alpha                      matlab.ui.control.EditField
        Label2                     matlab.ui.control.Label
        d                          matlab.ui.control.EditField
        Label3                     matlab.ui.control.Label
        a                          matlab.ui.control.EditField
        EnterNameEditFieldLabel    matlab.ui.control.Label
        EnterNameEditField         matlab.ui.control.EditField
        TextArea2                  matlab.ui.control.TextArea
        TextArea                   matlab.ui.control.TextArea
        WorkspaceButton            matlab.ui.control.Button
        RobotLabel                 matlab.ui.control.Label
        TextArea_2                 matlab.ui.control.TextArea
        ForwardKinematicsButton_2  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardKinematicsButton_2
        function ForwardKinematicsButton_2Pushed(app, event)
          try
           Th = str_process_22(app.Theta.Value);
           Alp = str_process_22(app.Alpha.Value);
            A = str_process_22(app.a.Value);
            D = str_process_22(app.d.Value);
           catch
           Th = str_process(app.Theta.Value);
           Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
           end
            B = app.joints.Value;
            for i=1:length(Alp)
                DH_para(i,:)=[Th(i),Alp(i),A(i),D(i)]  
            end
            O = app.EnterNameEditField.Value;
            F_K = Forward_Kinematics(O,DH_para,B)
            f = figure;
            uit = uitable(f,'ColumnWidth',{200,100,200},'Data',F_K);
        end

        % Button pushed function: WorkspaceButton
        function WorkspaceButtonPushed(app, event)
           try
            Th = str_process_22(app.Theta.Value);
            Alp = str_process_22(app.Alpha.Value);
            A = str_process_22(app.a.Value);
            D = str_process_22(app.d.Value);
           catch
            Th = str_process(app.Theta.Value);
            Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
            end
            O = app.EnterNameEditField.Value;
            B = app.joints.Value;
            for i=1:length(Alp)
                DH1(i,:)=[Th(i),Alp(i),A(i),D(i)];  
            end
            W = workspace(O,B,DH1) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Note';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Number of Joints';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.LabelEditField5 = uilabel(app.UIFigure);
            app.LabelEditField5.Text = 'Joint Types';
            app.joints = uieditfield(app.UIFigure, 'text');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'DH Parameters';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Theta';
            app.Theta = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Alpha';
            app.Alpha = uieditfield(app.UIFigure, 'text');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'd';
            app.d = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'text');
            app.EnterNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterNameEditFieldLabel.Text = 'Enter Name';
            app.EnterNameEditField = uieditfield(app.UIFigure, 'text');
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {'Enter R or P without any spaces.'; 'Eg: If links are Revolute and Prismatic enter RP'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Enter DH parameters separated by '','''};
            app.WorkspaceButton = uibutton(app.UIFigure, 'push');
            app.WorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceButtonPushed, true);
            app.WorkspaceButton.Text = 'Workspace';
            app.RobotLabel = uilabel(app.UIFigure);
            app.RobotLabel.Text = ' Robot';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Enter Theta and alpha in degrees'; ''};
            app.ForwardKinematicsButton_2 = uibutton(app.UIFigure, 'push');
            app.ForwardKinematicsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButton_2Pushed, true);
            app.ForwardKinematicsButton_2.Text = 'Forward Kinematics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_forward_kinematics_and_workspace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end