classdef IDK_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InverseDifferentialKinematicsButton  matlab.ui.control.Button
        Label4                          matlab.ui.control.Label
        LabelEditField                  matlab.ui.control.Label
        Theta                           matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Alpha                           matlab.ui.control.EditField
        Label2                          matlab.ui.control.Label
        d                               matlab.ui.control.EditField
        Label3                          matlab.ui.control.Label
        a                               matlab.ui.control.EditField
        LabelNumericEditField           matlab.ui.control.Label
        n                               matlab.ui.control.NumericEditField
        JointTypesLabel                 matlab.ui.control.Label
        joints                          matlab.ui.control.EditField
        TimeLabel                       matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        LabelTextArea                   matlab.ui.control.Label
        TextArea2                       matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        RobotArmLabel                   matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        JointcoordinatesEditFieldLabel  matlab.ui.control.Label
        JointcoordinatesEditField       matlab.ui.control.EditField
        qinitialEditFieldLabel          matlab.ui.control.Label
        qinitialEditField               matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % InverseDifferentialKinematicsButton
        function InverseDifferentialKinematicsButtonPushed(app, event)
            try
            Th = str_process(app.Theta.Value);
            Alp = str_process_22(app.Alpha.Value);
            A = str_process_22(app.a.Value);
            D = str_process_22(app.d.Value);
            Q = str_process_22(app.qinitialEditField.Value);
            JC = str_process_22(app.JointcoordinatesEditField.Value);
            T = str_process_22(app.TimeEditField.Value);
            Th = sym(Th,'real');
            catch
            Th = str_process(app.Theta.Value);
            Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
            Q = str_process(app.qinitialEditField.Value);
            JC = str_process(app.JointcoordinatesEditField.Value);
            Th = sym(Th,'real');Alp = sym(Alp,'real');
            A = sym(A,'real');D = sym(D,'real');
            
            end
            ptab(:,1) = Th';ptab(:,2) = Alp';
            ptab(:,3) = A';ptab(:,4) = D';
            r = Robot_5(app.n.Value,ptab,app.joints.Value);
            ID_K = IDK(r,JC,Q,T);
            f = figure;
            uit = uitable(f,'ColumnWidth',{200,100,200},'Data',ID_K);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InverseDifferentialKinematicsButton = uibutton(app.UIFigure, 'push');
            app.InverseDifferentialKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDifferentialKinematicsButtonPushed, true);
            app.InverseDifferentialKinematicsButton.Text = 'Inverse Differential Kinematics';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'DH Parameters';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Theta';
            app.Theta = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Alpha';
            app.Alpha = uieditfield(app.UIFigure, 'text');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'd';
            app.d = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'text');
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Number of Joints';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.JointTypesLabel = uilabel(app.UIFigure);
            app.JointTypesLabel.Text = '  Joint Types';
            app.joints = uieditfield(app.UIFigure, 'text');
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = '                   Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.JointcoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.JointcoordinatesEditFieldLabel.Text = 'Joint coordinates';
            app.JointcoordinatesEditField = uieditfield(app.UIFigure, 'text');
            app.qinitialEditFieldLabel = uilabel(app.UIFigure);
            app.qinitialEditFieldLabel.Text = 'q initial ';
            app.qinitialEditField = uieditfield(app.UIFigure, 'text');
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Note';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {'Enter R or P without any spaces.'; 'Eg: If links are Revolute and Prismatic enter RP'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Enter DH parameters separated by '','''};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Enter Theta and d in terms of syms and alpha in degrees'; ''};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Enter theta values in q initial and Joint coordinates in degrees'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'The program will take sometime to run. Be Patient for the results :)'};
            app.RobotArmLabel = uilabel(app.UIFigure);
            app.RobotArmLabel.Text = 'Robot Arm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IDK_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end