classdef Jac_Sing_htm_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Button3               matlab.ui.control.Button           % Compute_...
        Button4               matlab.ui.control.Button           % Compute_...
        Button5               matlab.ui.control.Button           % Compute_HTM
        LabelNumericEditField matlab.ui.control.Label            % Number o...
        n                     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField5       matlab.ui.control.Label            % Joint Types
        joints                matlab.ui.control.EditField       
        LabelEditField        matlab.ui.control.Label            % Theta
        Theta                 matlab.ui.control.EditField       
        Label                 matlab.ui.control.Label            % Alpha
        Alpha                 matlab.ui.control.EditField       
        Label2                matlab.ui.control.Label            % d
        d                     matlab.ui.control.EditField       
        Label3                matlab.ui.control.Label            % a
        a                     matlab.ui.control.EditField       
        Label4                matlab.ui.control.Label            % DH Param...
        LabelTextArea         matlab.ui.control.Label            % Note
        TextArea              matlab.ui.control.TextArea         % Enter DH...
        LabelTextArea2        matlab.ui.control.Label            % Note
        TextArea2             matlab.ui.control.TextArea         % Enter R ...
        LabelEditField6       matlab.ui.control.Label            % Joint  C...
        joint_C               matlab.ui.control.EditField       
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button3 button pushed function
        function Compute_Jacobian(app)
            q = str_process(app.joint_C.Value);
            q = sym(q,'real');
            try
            Th = str_process(app.Theta.Value);
            Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
            Th = sym(Th,'real');Alp = sym(Alp,'real');
            A = sym(A,'real');D = sym(D,'real');
            ptab(:,1) = Th';ptab(:,2) = Alp';
            ptab(:,3) = A';ptab(:,4) = D';
            r = Robot_6(app.n.Value,ptab,app.joints.Value,q);
            s = r.Compute_Jacobian();
            sc = arrayfun(@char, s, 'uniform', 0);
            catch
            Th = str_process_22(app.Theta.Value);
            Alp = str_process_22(app.Alpha.Value);
            A = str_process_22(app.a.Value);
            D = str_process_22(app.d.Value);
            ptab(:,1) = Th';ptab(:,2) = Alp';
            ptab(:,3) = A';ptab(:,4) = D';            
            r = Robot_6(app.n.Value,ptab,app.joints.Value,q);
            sc = r.Compute_Jacobian();
            end
            f = figure;
            uit = uitable(f,'ColumnWidth',{300,300,300},'Data',sc);
        end

        % Button5 button pushed function
        function Compute_HTM(app)
            q = str_process(app.joint_C.Value);
            q = sym(q,'real');            
            Th = str_process(app.Theta.Value);
            Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
            Th = sym(Th,'real');Alp = sym(Alp,'real');
            A = sym(A,'real');D = sym(D,'real');
            ptab(:,1) = Th';ptab(:,2) = Alp';
            ptab(:,3) = A';ptab(:,4) = D';
            r = Robot_6(app.n.Value,ptab,app.joints.Value,q);
            s = r.HTM();
            sc = arrayfun(@char, s, 'uniform', 0);
            f = figure;
            uit = uitable(f,'ColumnWidth',{200,100,200},'Data',sc);
        end

        % Button4 button pushed function
        function Compute_Singularity(app)
            q = str_process(app.joint_C.Value);
            q = sym(q,'real');            
            Th = str_process(app.Theta.Value);
            Alp = str_process(app.Alpha.Value);
            A = str_process(app.a.Value);
            D = str_process(app.d.Value);
            Th = sym(Th,'real');Alp = sym(Alp,'real');
            A = sym(A,'real');D = sym(D,'real');
            ptab(:,1) = Th';ptab(:,2) = Alp';
            ptab(:,3) = A';ptab(:,4) = D';
            r = Robot_6(app.n.Value,ptab,app.joints.Value,q);
            s1 = r.Compute_Singularity_1();
            f = figure;
            uit = uitable(f,'ColumnWidth',{200,100,200},'Data',s1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Compute_Jacobian);
            app.Button3.Text = 'Compute_Jacobian';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Compute_Singularity);
            app.Button4.Text = 'Compute_Singularity';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Compute_HTM);
            app.Button5.Text = 'Compute_HTM';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Number of Joints';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.LabelEditField5 = uilabel(app.UIFigure);
            app.LabelEditField5.Text = 'Joint Types';
            app.joints = uieditfield(app.UIFigure, 'text');
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Theta';
            app.Theta = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Alpha';
            app.Alpha = uieditfield(app.UIFigure, 'text');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'd';
            app.d = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'text');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'DH Parameters';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Note';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Enter DH parameters'; 'separated by '','''};
            app.LabelTextArea2 = uilabel(app.UIFigure);
            app.LabelTextArea2.Text = 'Note';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {'Enter R or P without any spaces.'; 'Eg: If links are Revolute and Prismatic enter RP'};
            app.LabelEditField6 = uilabel(app.UIFigure);
            app.LabelEditField6.Text = 'Joint  Co-ordinates';
            app.joint_C = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Jac_Sing_htm_2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end